classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Answer       matlab.ui.control.Label
        sum          matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Ball3        matlab.ui.control.EditField
        Label_12     matlab.ui.control.Label
        Ball2        matlab.ui.control.EditField
        Label_11     matlab.ui.control.Label
        Ball1        matlab.ui.control.EditField
        Label_10     matlab.ui.control.Label
        EditField_6  matlab.ui.control.EditField
        Label_9      matlab.ui.control.Label
        EditField_5  matlab.ui.control.EditField
        Label_8      matlab.ui.control.Label
        EditField_4  matlab.ui.control.EditField
        Label_7      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        EditField_2  matlab.ui.control.EditField
        EditField    matlab.ui.control.EditField
        Label_6      matlab.ui.control.Label
        Label_5      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Ball1
        function Ball1ValueChanged(app, event)

            
        end

        % Callback function
        function EditField_7ValueChanging(app, event)
            FAQ = event.Value;
            
        end

        % Value changed function: Ball2
        function Ball2ValueChanged(app, event)
            
        end

        % Value changed function: Ball3
        function Ball3ValueChanged(app, event)
                
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
      %      set(app.TextArea, get(app.Ball3.Value))
       predmet1 = str2double(app.Ball1.Value);
       if predmet1 < 0 || predmet1 > 100
           errordlg("ERROR")
           return
       end
       predmet2 = str2double(app.Ball2.Value);
       if predmet2 < 0 || predmet2 > 100
           errordlg("ERROR")
           return
       end
       predmet3 = str2double(app.Ball3.Value);
       if predmet3 < 0 || predmet3 > 100
           errordlg("ERROR")
           return
       end
       sum1 = predmet1+predmet2+predmet3;
       app.sum.Text = num2str(sum1);
       if (sum1 >= 250)
           app.Answer.Text = "Поздравляю!!!";
       else
           app.Answer.Text = "К сожалению, вы не прошли";
       end
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия, имя, отчество';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предметы';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы ЕГЭ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Проходной балл: 250';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Сумма баллов:';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Ball1 = uieditfield(app.UIFigure, 'text');
            app.Ball1.ValueChangedFcn = createCallbackFcn(app, @Ball1ValueChanged, true);
            app.Ball1.Value = '0';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.Ball2 = uieditfield(app.UIFigure, 'text');
            app.Ball2.ValueChangedFcn = createCallbackFcn(app, @Ball2ValueChanged, true);
            app.Ball2.Value = '0';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '';
            app.Ball3 = uieditfield(app.UIFigure, 'text');
            app.Ball3.ValueChangedFcn = createCallbackFcn(app, @Ball3ValueChanged, true);
            app.Ball3.Value = '0';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Расчитать';
            app.sum = uilabel(app.UIFigure);
            app.sum.Text = '';
            app.Answer = uilabel(app.UIFigure);
            app.Answer.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end