classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        cosx      matlab.ui.control.CheckBox
        Button    matlab.ui.control.Button
        sinx      matlab.ui.control.CheckBox
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value1 = app.sinx.Value;
            value2 = app.cosx.Value;
            x = 0:0.1:4*pi;
            y = sin(x);
            z = cos(x);
            hPlotData = plot(app.UIAxes, x, y);
            hold(app.UIAxes,'on');
            hPlotData1 = plot(app.UIAxes, x, z);
            if (value1 == false)
              delete(hPlotData);
            end
            if (value2 == false)
              delete(hPlotData1);
            end
            hold(app.UIAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 5 10 15];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'};
            app.UIAxes.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.8'; '-0.6'; '-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.sinx = uicheckbox(app.UIFigure);
            app.sinx.Text = 'График Синуса';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'Вычисление'; ''};
            app.cosx = uicheckbox(app.UIFigure);
            app.cosx.Text = 'График Косинуса';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end