classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        clear1_2  matlab.ui.control.Button
        built1_2  matlab.ui.control.Button
        clear1    matlab.ui.control.Button
        built1    matlab.ui.control.Button
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: built1
        function built1Pushed(app, event)
            x = -2:0.2:2;
            y = exp(-(x.^2));
            plot(app.UIAxes, x, y);
        end

        % Button pushed function: clear1
        function clear1ButtonPushed(app, event)
            x = -2:0.2:2;
            y = exp(-(x.^2));
            vyu = plot(app.UIAxes, x, y);
            delete(vyu);
        end

        % Button pushed function: built1_2
        function built1_2ButtonPushed(app, event)
            x = 0:0.1:4*pi;
            y = sin(x);
            plot(app.UIAxes2, x, y);
        end

        % Button pushed function: clear1_2
        function clear1_2ButtonPushed(app, event)
            x = -2:0.2:2;
            y = exp(-(x.^2));
            vyu = plot(app.UIAxes2, x, y);
            delete(vyu);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'y = e^(-x^2)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'sin(x)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.built1 = uibutton(app.UIFigure, 'push');
            app.built1.ButtonPushedFcn = createCallbackFcn(app, @built1Pushed, true);
            app.built1.Text = 'Построить';
            app.clear1 = uibutton(app.UIFigure, 'push');
            app.clear1.ButtonPushedFcn = createCallbackFcn(app, @clear1ButtonPushed, true);
            app.clear1.Text = 'Очистить';
            app.built1_2 = uibutton(app.UIFigure, 'push');
            app.built1_2.ButtonPushedFcn = createCallbackFcn(app, @built1_2ButtonPushed, true);
            app.built1_2.Text = 'Построить';
            app.clear1_2 = uibutton(app.UIFigure, 'push');
            app.clear1_2.ButtonPushedFcn = createCallbackFcn(app, @clear1_2ButtonPushed, true);
            app.clear1_2.Text = 'Очистить';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end