classdef project_matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        point       matlab.ui.control.NumericEditField
        Label_7     matlab.ui.control.Label
        order       matlab.ui.control.NumericEditField
        Label_6     matlab.ui.control.Label
        to          matlab.ui.control.NumericEditField
        Label_5     matlab.ui.control.Label
        from        matlab.ui.control.NumericEditField
        Label_4     matlab.ui.control.Label
        Button      matlab.ui.control.Button
        functionwo  matlab.ui.control.EditField
        Label       matlab.ui.control.Label
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)

            x = app.from.Value:((app.to.Value-app.from.Value)/10000):app.to.Value;
            f = str2func(['@(x)' app.functionwo.Value]);
            y = f(x);
            plot(app.UIAxes, x, y);
            hold(app.UIAxes,'on');
            syms x;
            f1 = taylor(str2sym(app.functionwo.Value), x, app.point.Value, 'Order', app.order.Value);
            fplot(app.UIAxes, f1, [app.from.Value, app.to.Value]);
            grid(app.UIAxes, 'on')
            hold(app.UIAxes,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Ваша функция'; ''};
            app.functionwo = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Считать';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'От';
            app.from = uieditfield(app.UIFigure, 'numeric');
            app.from.Value = -10;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'До';
            app.to = uieditfield(app.UIFigure, 'numeric');
            app.to.Value = 10;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Порядок';
            app.order = uieditfield(app.UIFigure, 'numeric');
            app.order.Value = 5;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Точка';
            app.point = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end